classdef nas_projekt_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DIrectkinematicsLabel           matlab.ui.control.Label
        q1SliderLabel                   matlab.ui.control.Label
        q1_DirKinIn_Slider              matlab.ui.control.Slider
        q2SliderLabel                   matlab.ui.control.Label
        q2_DirKinIn_Slider              matlab.ui.control.Slider
        q3SliderLabel                   matlab.ui.control.Label
        q3_DirKinIn_Slider              matlab.ui.control.Slider
        InputjointvaluesradLabel        matlab.ui.control.Label
        q1EditFieldLabel                matlab.ui.control.Label
        q1_DirKinIn_EditField           matlab.ui.control.NumericEditField
        q2EditFieldLabel                matlab.ui.control.Label
        q2_DirKinIn_EditField           matlab.ui.control.NumericEditField
        q3EditFieldLabel                matlab.ui.control.Label
        q3_DirKinIn_EditField           matlab.ui.control.NumericEditField
        EndeffectorpositioncmLabel      matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        X_DirKinOut_EditField           matlab.ui.control.EditField
        YEditFieldLabel                 matlab.ui.control.Label
        Y_DirKinOut_EditField           matlab.ui.control.EditField
        ZEditFieldLabel                 matlab.ui.control.Label
        Z_DirKinOut_EditField           matlab.ui.control.EditField
        InversekinematicsLabel          matlab.ui.control.Label
        InputendeffectorpositioncmLabel  matlab.ui.control.Label
        XEditFieldLabel_2               matlab.ui.control.Label
        X_InvKinIn_EditField            matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        Y_InvKinIn_EditField            matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        Z_InvKinIn_EditField            matlab.ui.control.NumericEditField
        JointvaluesradLabel             matlab.ui.control.Label
        q1EditField_2Label              matlab.ui.control.Label
        q1_InvKinOut_EditField          matlab.ui.control.NumericEditField
        q2EditField_2Label              matlab.ui.control.Label
        q2_InvKinOut_EditField          matlab.ui.control.NumericEditField
        q3EditField_2Label              matlab.ui.control.Label
        q3_InvKinOut_EditField          matlab.ui.control.NumericEditField
        CalculatedirkinButton           matlab.ui.control.Button
        CalculateinvkinButton           matlab.ui.control.Button
        DirectkinematicsinputtypeButtonGroup  matlab.ui.container.ButtonGroup
        SlidersInputTypeButton          matlab.ui.control.RadioButton
        EditfieldInputTypeButton        matlab.ui.control.RadioButton
        InitializerobotoriginButton     matlab.ui.control.Button
        AutonomousmodepatternEditFieldLabel  matlab.ui.control.Label
        AutonomousmodepatternEditField  matlab.ui.control.EditField
        StartautonomousmodeButton       matlab.ui.control.Button
        isdelimiterbetweenvaluesLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        SimulinkModelName = "simulation_three_motors";
        SimulinkJoint1ConstantBlockName = "joint1";
        SimulinkJoint2ConstantBlockName = "joint2";
        SimulinkJoint3ConstantBlockName = "joint3";
        
        %članci
        %TODO: ovo stavit na stvarne vrijednosti,, u [cm]
        %d1 = 4; %udaljenost nultog od prvog zgloba
        %d2 = 14; %udaljenost prvog od drugog (dio spoj23, valjda)
        %d3 = 12; %udaljenost drugog od treceg (virtualnog) (dio spoj23, valjda)
        %d4 = 6; %duzina onog djela koji visi
        %a5 = 1; %po y0 osi za dio koji visi, nes jako malo

        d1 = 5.5; %udaljenost nultog od prvog zgloba
        d2 = 19.5; %udaljenost prvog od drugog (dio spoj23, valjda)
        d3 = 17.5; %udaljenost drugog od treceg (virtualnog) (dio spoj23, valjda)
        d4 = 8; %duzina onog djela koji visi
        a5 = 1; %po y0 osi za dio koji visi, nes jako malo


        %robot origin
        x_px_base = -1;
        y_px_base = -1;

        %cam_name, ovisno o kameri promjenit
        %cam_name = "/dev/video2";
        cam_name = "Logi C270 HD WebCam";

        R_0_S1 = [0 1 0; 1 0 0; 0 0 -1]; %blize vratima,, s lijeve strane daske
        R_0_S2 = [0 -1 0; -1 0 0; 0 0 -1]; %do prozora,, s desne strane daske
        
        %ovisno u koordinatnom sustavu kamere promjenit, to podesavam u
        %startupFnc, ne moze tu
        R_0_S 
    end
    
    methods (Access = public)
        
        function p0_T = directKinematics(app, q1, q2, q3)
            %     Args:
            %         q1: zakret prvog zgloba [rad]
            %         q2: zakret drugog zgloba [rad]
            %         q3: zakret treceg zgloba [rad]
            %      
            %     Return:
            %         p0_T: udaljenost baze od vrha alata (u k.s. baze tj nultog
            %               zgloba), 3x1 vektor
            %         z0_T: smjer z osi alata (u k.s. baze tj nultog zgloba), 3x1
            %               vektor
            
                %isprobao za vise kutaeva i cini mi se da dobro radi
                %TODO: ovo d1:d4 stavit na prave vrijednosti (u metrima ja bi rekao)
            
                d1 = app.d1; %udaljenost nultog od prvog zgloba
                d2 = app.d2; %udaljenost prvog od drugog (dio spoj23, valjda)
                d3 = app.d3; %udaljenost drugog od treceg (virtualnog) (dio spoj23, valjda)
                
                d4 = app.d4; %duzina onog djela koji visi
                a5 = app.a5; %po y0 osi za dio koji visi
            
                T0_1 = [cos(q1), 0, -cos(pi/2-q1), 0;
                        sin(q1), 0, sin(pi/2-q1), 0;
                        0, -1, 0, d1;
                        0, 0, 0, 1];
                
                T1_2 = [cos(q2), -cos(pi/2-q2), 0, d2*sin(q2);
                        sin(q2), sin(pi/2-q2), 0, -d2*cos(q2);
                        0, 0, 1, 0;
                        0, 0, 0, 1];
            
                 
                T2_3 = [cos(q3), -cos(pi/2-q3), 0, d3*sin(q3);
                        sin(q3), sin(pi/2-q3), 0, -d3*cos(q3);
                        0, 0, 1, 0;
                        0, 0, 0, 1];
            
                T4_0 = [0, 0, 0, 0;
                        0, 0, 0, a5;
                        0, 0, 0, -d4;
                        0, 0, 0, 0];
                   
                T0_T = T0_1 * T1_2 * T2_3 + T4_0; %samo spustio za toliko od zadnjeg virtualnog zgloba po z0 osi (mozda i po y0)
            
                p0_T = T0_T(1:3, 4); %mislim da je dobar
            %     z0_T = T0_T(1:3, 3) %nije dobar jer nije [0,0,-1] ali nije ni bitno jer se to nigdje poslije ne provjerava ni upotrebljava, mogao bi jedino rucno promjenit da je [0, 0, -1]
                z0_T = [0; 0; -1];

        end
        
        function [q1, q2, q3] = inverseKinematics(app, p0_T) %dovrsit nju--jesam, i isprobat, dodat args, napravit da kad promjenis negdje q da se svugdje updatea qevi isto tako i za xyz
            %Args:
            %   p0_T: 3x1 vector containing x, y, z position of end effector in
            %         base coordinate frame
            %Returns:
            %   q1, q2, q3: joint values for each joint respectively in
            %   [rad]
            
            %podesit
            d1 = app.d1;
            d2 = app.d2;
            d3 = app.d3;
            d4 = app.d4;
            a5 = app.a5;
            
            w1 = p0_T(1);
            w2 = p0_T(2);
            w3 = p0_T(3);
            
            eqns = @(q) [
                d2*sin(q(2))*cos(q(1)) + d3*sin(q(2))*cos(q(1))*cos(q(3)) + d3*sin(q(3))*cos(q(1))*cos(q(2)) - w1;
                a5 + d2*sin(q(1))*sin(q(2)) + d3*sin(q(1))*sin(q(2))*cos(q(3)) + d3*sin(q(1))*sin(q(3))*cos(q(2)) - w2;
                d1 + d2*cos(q(2)) - d3*sin(q(2))*sin(q(3)) + d3*cos(q(2))*cos(q(3)) - d4 - w3;
            ];
            
            q0 = [0; 0; 0]; %(-pi+pi)/2
            
            [q, ~] = fsolve(@(q)eqns(q), q0) %rjesava po q (q1 q2 q3)
            
            q_wrapped = wrapToPi(q)
            
            q1 = q_wrapped(1);
            q2 = q_wrapped(2);
            q3 = q_wrapped(3);
            q3 = -q3;
        end
        
        function results = moveJoints(app, q1, q2, q3, isReversed)
            %Args:
            %   q1, q2, q3: desired joint values in [rad]
            %   isReversed: if false, move joints in sequence: 1 2 3, it
            %               true, move in sequence: 3 2 1
            %Returns:
            %   None

            q1_deg = round(rad2deg(q1)); %zaokruzuje (0 decimala)
            q2_deg = round(rad2deg(q2));
            q3_deg = round(rad2deg(q3));
            
            %update joints
            if isReversed == false %1 2 3
                set_param(strcat(app.SimulinkModelName, '/',app.SimulinkJoint1ConstantBlockName), 'Value', mat2str(q1_deg));
                pause(2); %u sekundama, da prvo jednog pomakne, pauza, pa drugog, pauza, pa treceg,, mozda i bez toga
                set_param(strcat(app.SimulinkModelName, '/',app.SimulinkJoint2ConstantBlockName), 'Value', mat2str(q2_deg));
                pause(2);
                set_param(strcat(app.SimulinkModelName, '/',app.SimulinkJoint3ConstantBlockName), 'Value', mat2str(q3_deg));
                pause(3);
            else %3 2 1
                set_param(strcat(app.SimulinkModelName, '/',app.SimulinkJoint3ConstantBlockName), 'Value', mat2str(q3_deg));
                pause(2); %u sekundama, da prvo jednog pomakne, pauza, pa drugog, pauza, pa treceg,, mozda i bez toga
                set_param(strcat(app.SimulinkModelName, '/',app.SimulinkJoint2ConstantBlockName), 'Value', mat2str(q2_deg));
                pause(2);
                set_param(strcat(app.SimulinkModelName, '/',app.SimulinkJoint1ConstantBlockName), 'Value', mat2str(q1_deg));
                pause(3);
            end

            %mozda i da se vrati onda u 0,0,0, inace nece moc dalje hvatat
            %ja msm, ili to samo napravit za autonomous mode
        end
        
        function [x, y, z] = getCentroid(app, color_to_segment)
            %ourGetCentroid
            %Args:
            %   color: string, red, green, blue, black
            %Return:
            %   [x,y,z]: object centroid in L0 coordinate frame
            
            %%%%consts,,, ovo stavit kao atribute klase
            if app.x_px_base == -1 || app.y_px_base == -1
                uialert(app.UIFigure, 'Robot origin has to be initialized first. Use Initialize robot origin button.', 'Error', 'Icon', 'error');
                return
            end

            
            %ovo stavit kao atribute klase,,, mozda i ne treba
            %black
            h_min_black = 0.470;
            h_max_black = 0.687;
            
            s_min_black = 0.283;
            s_max_black = 0.750;
            
            v_min_black = 0.075;
            v_max_black = 0.322;
            
            %blue
            h_min_blue = 0.533;
            h_max_blue = 0.604;
            
            s_min_blue = 0.344;
            s_max_blue = 0.869;
            
            v_min_blue = 0.329;
            v_max_blue = 1.000;
            
            %yellow
            h_min_yellow = 0.157;
            h_max_yellow = 0.230;
            
            s_min_yellow = 0.269;
            s_max_yellow = 0.578;
            
            v_min_yellow = 0.584;
            v_max_yellow = 0.824;
            
            %green
            h_min_green = 0.292;
            h_max_green = 0.357;
            
            s_min_green = 0.199;
            s_max_green = 0.666;
            
            v_min_green = 0.262;
            v_max_green = 0.886;
            
            
            %i cam postavis kao atribut
            %px_per_cm = 25.6248;
            %px_per_cm = 16.6;
            px_per_cm = 14.25;
            
            %
            desiredSurfaceBlock = 4500;
            desiredSurfaceDropOff = 3.5*4500; %vidjet i promjenit,, s ovim okej radi
            
            %R_0_S1 = [0 1 0; 1 0 0; 0 0 -1]; %blize vratima
            %R_0_S2 = [0 -1 0; -1 0 0; 0 0 -1]; %do prozora
            
            cam = webcam(app.cam_name);
            img = snapshot(cam);
            imshow(img); 

            image_hsv = rgb2hsv(img);
            
            % Use the switch-case statement
            switch color_to_segment
                case 'green'
                    % Action for case 1
                    h_min = h_min_green;
                    h_max = h_max_green;
            
                    s_min = s_min_green;
                    s_max = s_max_green;
            
                    v_min = v_min_green;
                    v_max = v_max_green;
                    
                case 'yellow'
                    % Action for case 2
                    h_min = h_min_yellow;
                    h_max = h_max_yellow;
            
                    s_min = s_min_yellow;
                    s_max = s_max_yellow;
            
                    v_min = v_min_yellow;
                    v_max = v_max_yellow;
                    
                case 'blue'
                    % Action for case 3
                    h_min = h_min_blue;
                    h_max = h_max_blue;
            
                    s_min = s_min_blue;
                    s_max = s_max_blue;
            
                    v_min = v_min_blue;
                    v_max = v_max_blue;
            
                case 'black'
                    % Action for case 3
                    h_min = h_min_black;
                    h_max = h_max_black;
            
                    s_min = s_min_black;
                    s_max = s_max_black;
            
                    v_min = v_min_black;
                    v_max = v_max_black;
                    
                otherwise
                    % Action for other cases
                    disp('Entered color isnt green/yellow/blue/black');
                    return %Ili nesto, onaj figure error da izbaci
            end
            
            h_color=image_hsv(:,:,1) >= h_min & image_hsv(:,:,1) <= h_max;
            s_color=image_hsv(:,:,2) >= s_min & image_hsv(:,:,2) <= s_max;
            v_color=image_hsv(:,:,3) >= v_min & image_hsv(:,:,3) <= v_max;
            
            color = h_color & s_color & v_color;
            
            imshow(color)


            se=strel('disk',2);
            eroded_color=imerode(color,se);
            
            se2=strel('disk',10);
            closed_color=imclose(eroded_color,se2);
            
            imshow(closed_color)
            
            center = regionprops(closed_color, 'Centroid', 'Area');
            
            % %%%%
            % Choose the desired object by specifying its surface
            % desiredSurface = 4500;  % otprilike 4500 za kockicu
            if strcmp(color_to_segment,'black')
                desiredSurface = desiredSurfaceDropOff;
            else
                desiredSurface = desiredSurfaceBlock;
            end    
            
            % Initialize variables to store the closest centroid and its difference from the desired surface
            closestCentroid = [];
            minDifference = Inf;
            
            %povrsina u cm^2 treba bit oko 2*4 cm^2,, trazi onog koji najmanje odstupa
            %od zeljene povrsine
            for i = 1:numel(center)
                % Check if the object's surface is close to the desired surface
                surfaceDifference = abs(center(i).Area - desiredSurface); %area je u px, moramo vidjet koliko je to u cm ili m ^2
            %     center(i).Area
            
                if surfaceDifference < minDifference
                    closestCentroid = center(i).Centroid;
                    minDifference = surfaceDifference;
                end
            end
            
            if ~isempty(closestCentroid)
                x_color = closestCentroid(1);
                y_color = closestCentroid(2);
            
                imshow(img);
                hold on;
                plot(x_color, y_color, 'r+', 'MarkerSize', 10);
                hold off;
            else
                disp('No object found with a surface close to the desired surface.');
            end
            x_color
            y_color
            
            
            %pretvaranje u ks robota,, msms da je dobro
            
            x_px = x_color - app.x_px_base; %centriranje
            y_px = y_color - app.y_px_base;
            
            x_px_cm = x_px / px_per_cm; %pretvaranje iz px u cm
            y_px_cm = y_px / px_per_cm;
            
            %R_0_S = R_0_S2; %ovo ovisno o setupu
            
            robot_ks_obj = app.R_0_S * [x_px_cm; y_px_cm; 0]; %stavljanje u ks robota
            if robot_ks_obj(1) <= 13.5
                robot_ks_obj(3) = -4.25;
            elseif 13.5 < robot_ks_obj(1) && robot_ks_obj(1) <= 21.5
                robot_ks_obj(3) = -3;
            else
                robot_ks_obj(3) = -2;
            end

            %robot_ks_obj(3) = -3; %jer je baza na povisenom, to jos treba isprobavat, prije je bilo 1.8 kao sredina kockice nes tako
            
            robot_ks_obj

            x = robot_ks_obj(1);
            y = robot_ks_obj(2);
            z = robot_ks_obj(3);
        end
        
        function results = Joint1Callback(app, value)
            app.q1_InvKinOut_EditField.Value = value; %mozda pretvorit u double iz stringa ma da nez jel string (to je double ja msm)
            drawnow;
        end

        function results = Joint2Callback(app, value)
            app.q2_InvKinOut_EditField.Value = value;
            drawnow;
        end

        function results = Joint3Callback(app, value)
            app.q3_InvKinOut_EditField.Value = value;
            drawnow;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.SimulinkModelName = "//dodat";
            %app.SimulinkJoint1ConstantBlockName = "//dodat";
            %app.SimulinkJoint2ConstantBlockName = "//dodat";
            %app.SimulinkJoint3ConstantBlockName = "//dodat";

            app.q1_DirKinIn_EditField.Enable = false;
            app.q2_DirKinIn_EditField.Enable = false;
            app.q3_DirKinIn_EditField.Enable = false;

            %u metrima treba ja msm,,, za sad u cm
            %TODO: ovo stavit na stvarne vrijednosti
            %app.d1 = 5.5; %udaljenost nultog od prvog zgloba
            %app.d2 = 23; %udaljenost prvog od drugog (dio spoj23, valjda)
            %app.d3 = 22.5; %udaljenost drugog od treceg (virtualnog) (dio spoj23, valjda)
            %app.d4 = 8; %duzina onog djela koji visi
            %app.a5 = 1; %po y0 osi za dio koji visi, nes jako malo

            %ovisno o kameri koju koristim
            app.R_0_S = app.R_0_S2

            %disp(app.SimulinkModelName);
            %disp(app.SimulinkJoint1ConstantBlockName);
            %disp(app.SimulinkJoint2ConstantBlockName);
            %disp(app.SimulinkJoint3ConstantBlockName);
            %disp([app.SimulinkModelName, "/", app.SimulinkJoint1ConstantBlockName]); 
            %disp([app.SimulinkModelName '/' app.SimulinkJoint1ConstantBlockName]);
            %disp(concat(app.SimulinkModelName, '/',app.SimulinkJoint1ConstantBlockName))
            %disp(strcat(app.SimulinkModelName, '/',app.SimulinkJoint1ConstantBlockName))

            global hApp;
            hApp = app;

        end

        % Button pushed function: CalculatedirkinButton
        function CalculatedirkinButtonPushed(app, event)
            selectedInputType = app.DirectkinematicsinputtypeButtonGroup.SelectedObject;
            disp(selectedInputType);

            if selectedInputType == app.SlidersInputTypeButton 
                q1 = app.q1_DirKinIn_Slider.Value; %i za slider i za editfield data type je double
                q2 = app.q2_DirKinIn_Slider.Value;
                q3 = app.q3_DirKinIn_Slider.Value;
            else
                q1 = app.q1_DirKinIn_EditField.Value;
                q2 = app.q2_DirKinIn_EditField.Value;
                q3 = app.q3_DirKinIn_EditField.Value;
            end

            %disp(q1);
            %disp(q2);
            %disp(q3);

            %disp(class(q1)); 
            %disp(class(q2));
            %disp(class(q3));

            %q1 q2 q3 su u radijanima
            q3 = -q3;
            p0_T = app.directKinematics(q1, q2, q3);

            % Display end effector values
            app.X_DirKinOut_EditField.Value = mat2str(round(p0_T(1), 2));
            app.Y_DirKinOut_EditField.Value = mat2str(round(p0_T(2), 2));
            app.Z_DirKinOut_EditField.Value = mat2str(round(p0_T(3), 2));

            %TODO:
            %da se ovi zakreti posalju na motore tj u simulink (setparam..)
            app.moveJoints(q1, q2, q3, false); %odkomentirat poslije
        end

        % Selection changed function: 
        % DirectkinematicsinputtypeButtonGroup
        function DirectkinematicsinputtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DirectkinematicsinputtypeButtonGroup.SelectedObject;
            disp(selectedButton);
            
            if selectedButton == app.SlidersInputTypeButton
                app.q1_DirKinIn_Slider.Enable = true;
                app.q2_DirKinIn_Slider.Enable = true;
                app.q3_DirKinIn_Slider.Enable = true;

                app.q1_DirKinIn_EditField.Enable = false;
                app.q2_DirKinIn_EditField.Enable = false;
                app.q3_DirKinIn_EditField.Enable = false;
            else
                app.q1_DirKinIn_Slider.Enable = false;
                app.q2_DirKinIn_Slider.Enable = false;
                app.q3_DirKinIn_Slider.Enable = false;

                app.q1_DirKinIn_EditField.Enable = true;
                app.q2_DirKinIn_EditField.Enable = true;
                app.q3_DirKinIn_EditField.Enable = true;
            end
            drawnow;
        end

        % Button pushed function: CalculateinvkinButton
        function CalculateinvkinButtonPushed(app, event)
            x = app.X_InvKinIn_EditField.Value;
            y = app.Y_InvKinIn_EditField.Value;
            z = app.Z_InvKinIn_EditField.Value;

            [q1, q2, q3] = app.inverseKinematics([x, y, z]); %u radijanima

            %display joint values,, ovo ces morat promjenit da se dinamicki
            %updateaju zglobovi s obzirom na trenutni zakret motora (iz
            %simulinka callback, kao i za gui lab)
            %q3 = -q3;
            app.q1_InvKinOut_EditField.Value = (round(q1, 2));
            app.q2_InvKinOut_EditField.Value = (round(q2, 2));
            app.q3_InvKinOut_EditField.Value = (round(q3, 2));

            %TODO:
            %da se ovi zakreti posalju na motore tj u simulink
            app.moveJoints(q1, q2, q3, false); %odkomentirat poslije
        end

        % Button pushed function: InitializerobotoriginButton
        function InitializerobotoriginButtonPushed(app, event)
            %za dobit di nam se nalazi robot, njegovo ishodiste koje ce bit ishodiste
            %ovom img ks (getRobotOrigin)            
            
            %cam = webcam("/dev/video2");
            cam = webcam(app.cam_name);
            img = snapshot(cam);
            imshow(img);
            % Prompt the user to click on the image,, za dobit koordinate na koje
            % kliknes
            disp('Click on the image to get pixel coordinates.');
            disp('Press Enter or click right mouse button to finish.');
            [x, y] = ginput();  % Get the clicked pixel coordinates
            % Display the clicked coordinates
            disp('Clicked pixel coordinates:');
            disp([x, y]);

            app.x_px_base = x
            app.y_px_base = y

            uialert(app.UIFigure, 'Robot origin in pixels successfully initialized', 'Initialized origin', 'Icon', 'success');

        end

        % Button pushed function: StartautonomousmodeButton
        function StartautonomousmodeButtonPushed(app, event)
            %disp(app.AutonomousmodepatternEditField.Value);
            str_pattern = app.AutonomousmodepatternEditField.Value;
            array_str_pattern = split(str_pattern, ",");
            %disp(array_str_pattern);
            %disp(array_str_pattern(1));
            %disp(array_str_pattern{1}); %cell array (sta god to bilo) pa moras indeksirat sa curly braces {}
            %disp(numel(array_str_pattern));

            %check for invalid input
            for i=1:numel(array_str_pattern)
                if ~strcmp(array_str_pattern{i}, 'blue') && ~strcmp(array_str_pattern{i}, 'yellow') && ~strcmp(array_str_pattern{i}, 'green')
                    uialert(app.UIFigure, 'Invalid input. Available input: blue,green,yellow. Delimiter has to be , (comma)', 'Error', 'Icon', 'error');
                    return
                end
            end
            
            %app.getCentroid(array_str_pattern{1});
            %[x_black, y_black, z_black] = app.getCentroid("black");
            %[q1_black, q2_black, q3_black] = app.inverseKinematics([x_black, y_black, z_black]);
            q1_offset = 8*pi/180;
            for i=1:numel(array_str_pattern)
                %odi u 0,0,0 (q)
                app.moveJoints(0,0,0, true); %rad

                %nadi centroid neke boje
                [x, y, z] = app.getCentroid(array_str_pattern{i}); %color to pick

                %nadi zakrete preko inverzne
                [q1, q2, q3] = app.inverseKinematics([x, y, z]);

                %posalji ga po objekt
                app.moveJoints(q1+q1_offset, q2, q3, false);

                %vrati ga u 0,0,0 (q)
                app.moveJoints(0, 0, 0, true);

                %nadi centroid crne boje
                [x_black, y_black, z_black] = app.getCentroid('black'); %nece uvijek centroid bit isti jer kako slazes kockice bit ce sve manje prostora pa i drugaciji centroid
                
                %nadi zakrete za dropoff
                [q1_black, q2_black, q3_black] = app.inverseKinematics([x_black, y_black, z_black]);

                %posalji ga na dropoff point(crno)
                app.moveJoints(q1_black+q1_offset, q2_black, q3_black, false);

            end

        end

        % Value changed function: q1_InvKinOut_EditField
        function q1_InvKinOut_EditFieldValueChanged(app, event)
            value = app.q1_InvKinOut_EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DIrectkinematicsLabel = uilabel(app.UIFigure);
            app.DIrectkinematicsLabel.Text = 'DIrect kinematics:';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1_DirKinIn_Slider = uislider(app.UIFigure);
            app.q1_DirKinIn_Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q1_DirKinIn_Slider.MajorTicks = [-3.14159265358979 -1.04159265358979 0 1.05840734641021 3.14159265358979];
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2_DirKinIn_Slider = uislider(app.UIFigure);
            app.q2_DirKinIn_Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q2_DirKinIn_Slider.MajorTicks = [-3.14159265358979 -1.04159265358979 0 1.05840734641021 3.14159265358979];
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3_DirKinIn_Slider = uislider(app.UIFigure);
            app.q3_DirKinIn_Slider.Limits = [-3.14159265358979 3.14159265358979];
            app.q3_DirKinIn_Slider.MajorTicks = [-3.14159265358979 -1.04159265358979 0 1.05840734641021 3.14159265358979];
            app.InputjointvaluesradLabel = uilabel(app.UIFigure);
            app.InputjointvaluesradLabel.Text = 'Input joint values [rad]:';
            app.q1EditFieldLabel = uilabel(app.UIFigure);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1_DirKinIn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1_DirKinIn_EditField.Limits = [-3.14159265358979 3.14159265358979];
            app.q2EditFieldLabel = uilabel(app.UIFigure);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2_DirKinIn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2_DirKinIn_EditField.Limits = [-3.1416 3.1416];
            app.q3EditFieldLabel = uilabel(app.UIFigure);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3_DirKinIn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3_DirKinIn_EditField.Limits = [-3.1416 3.1416];
            app.EndeffectorpositioncmLabel = uilabel(app.UIFigure);
            app.EndeffectorpositioncmLabel.Text = 'End effector position [cm]:';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X_DirKinOut_EditField = uieditfield(app.UIFigure, 'text');
            app.X_DirKinOut_EditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y_DirKinOut_EditField = uieditfield(app.UIFigure, 'text');
            app.Y_DirKinOut_EditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z_DirKinOut_EditField = uieditfield(app.UIFigure, 'text');
            app.Z_DirKinOut_EditField.Editable = 'off';
            app.InversekinematicsLabel = uilabel(app.UIFigure);
            app.InversekinematicsLabel.Text = 'Inverse kinematics:';
            app.InputendeffectorpositioncmLabel = uilabel(app.UIFigure);
            app.InputendeffectorpositioncmLabel.Text = 'Input end effector position [cm]:';
            app.XEditFieldLabel_2 = uilabel(app.UIFigure);
            app.XEditFieldLabel_2.Text = 'X';
            app.X_InvKinIn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel_2 = uilabel(app.UIFigure);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Y_InvKinIn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Z_InvKinIn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.JointvaluesradLabel = uilabel(app.UIFigure);
            app.JointvaluesradLabel.Text = 'Joint values [rad]:';
            app.q1EditField_2Label = uilabel(app.UIFigure);
            app.q1EditField_2Label.Text = 'q1';
            app.q1_InvKinOut_EditField = uieditfield(app.UIFigure, 'numeric');
            app.q1_InvKinOut_EditField.Limits = [-3.1416 3.1416];
            app.q1_InvKinOut_EditField.ValueChangedFcn = createCallbackFcn(app, @q1_InvKinOut_EditFieldValueChanged, true);
            app.q1_InvKinOut_EditField.Editable = 'off';
            app.q2EditField_2Label = uilabel(app.UIFigure);
            app.q2EditField_2Label.Text = 'q2';
            app.q2_InvKinOut_EditField = uieditfield(app.UIFigure, 'numeric');
            app.q2_InvKinOut_EditField.Limits = [-3.1416 3.1416];
            app.q2_InvKinOut_EditField.Editable = 'off';
            app.q3EditField_2Label = uilabel(app.UIFigure);
            app.q3EditField_2Label.Text = 'q3';
            app.q3_InvKinOut_EditField = uieditfield(app.UIFigure, 'numeric');
            app.q3_InvKinOut_EditField.Limits = [-3.1416 3.1416];
            app.q3_InvKinOut_EditField.Editable = 'off';
            app.CalculatedirkinButton = uibutton(app.UIFigure, 'push');
            app.CalculatedirkinButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatedirkinButtonPushed, true);
            app.CalculatedirkinButton.Text = 'Calculate dir. kin.';
            app.CalculateinvkinButton = uibutton(app.UIFigure, 'push');
            app.CalculateinvkinButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateinvkinButtonPushed, true);
            app.CalculateinvkinButton.Text = 'Calculate inv. kin';
            app.DirectkinematicsinputtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.DirectkinematicsinputtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DirectkinematicsinputtypeButtonGroupSelectionChanged, true);
            app.DirectkinematicsinputtypeButtonGroup.Title = 'Direct kinematics input type';
            app.SlidersInputTypeButton = uiradiobutton(app.DirectkinematicsinputtypeButtonGroup);
            app.SlidersInputTypeButton.Text = 'Sliders';
            app.SlidersInputTypeButton.Value = true;
            app.EditfieldInputTypeButton = uiradiobutton(app.DirectkinematicsinputtypeButtonGroup);
            app.EditfieldInputTypeButton.Text = 'Edit fields';
            app.InitializerobotoriginButton = uibutton(app.UIFigure, 'push');
            app.InitializerobotoriginButton.ButtonPushedFcn = createCallbackFcn(app, @InitializerobotoriginButtonPushed, true);
            app.InitializerobotoriginButton.Text = 'Initialize robot origin';
            app.AutonomousmodepatternEditFieldLabel = uilabel(app.UIFigure);
            app.AutonomousmodepatternEditFieldLabel.Text = 'Autonomous mode pattern';
            app.AutonomousmodepatternEditField = uieditfield(app.UIFigure, 'text');
            app.StartautonomousmodeButton = uibutton(app.UIFigure, 'push');
            app.StartautonomousmodeButton.ButtonPushedFcn = createCallbackFcn(app, @StartautonomousmodeButtonPushed, true);
            app.StartautonomousmodeButton.Text = 'Start autonomous mode';
            app.isdelimiterbetweenvaluesLabel = uilabel(app.UIFigure);
            app.isdelimiterbetweenvaluesLabel.Text = '"," is delimiter between values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nas_projekt_gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end